classdef ToolBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        processBtn      matlab.ui.control.Button
        play            matlab.ui.control.Button
        filePicker      matlab.ui.control.Button
        ListBoxLabel    matlab.ui.control.Label
        ListBox         matlab.ui.control.ListBox
        TabGroup        matlab.ui.container.TabGroup
        AlsTab          matlab.ui.container.Tab
        RoomZLabel      matlab.ui.control.Label
        roomZ           matlab.ui.control.Knob
        RoomYLabel      matlab.ui.control.Label
        roomY           matlab.ui.control.Knob
        RoomXLabel      matlab.ui.control.Label
        roomX           matlab.ui.control.Knob
        beamformingTab  matlab.ui.container.Tab
        RoomXLabel_2    matlab.ui.control.Label
        roomX_2         matlab.ui.control.Knob
        RoomYLabel_2    matlab.ui.control.Label
        roomY_2         matlab.ui.control.Knob
        RoomZLabel_2    matlab.ui.control.Label
        roomZ_2         matlab.ui.control.Knob
        selectedLabel   matlab.ui.control.Label
        selected        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        x % Description
        fs % Description
        path = ""
        file
        alsRoom = [7,5,3];
        beamRoom = [15.5 9.8 7.5];
    end
    
    methods (Access = private)
        
        function proccess(app,to_run)
            if strcmp('als',to_run)
                [app.x,app.fs] = als(app.x,app.fs,app.alsRoom);
            elseif strcmp('beam',to_run)
                [app.x,app.fs] = pwd_dpd_music_beamforming(app.x,app.fs,app.alsRoom);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_script();
        end

        % Button pushed function: processBtn
        function processBtnPushed(app, event)
            if app.path ~= ""
                app.processBtn.Enable = 'off';
                for k=1:length(app.selected.Items)
                    proccess(app,app.selected.Items{k})
                end
                app.processBtn.Enable = 'on';
            else
                alert("Choose file");
            end
       
        end

        % Button pushed function: play
        function playPushed(app, event)
            soundsc(app.x, app.fs);
        end

        % Button pushed function: filePicker
        function filePickerButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            disp(app.file)
            [app.x,app.fs] = audioread(strcat(app.path,app.file));
        end

        % Value changed function: roomX
        function roomXValueChanged(app, event)
            value = app.roomX.Value;
            app.alsRoom(1)=value;
        end

        % Value changed function: roomY
        function roomYValueChanged(app, event)
            value = app.roomY.Value;
            app.alsRoom(2)=value;
        end

        % Value changed function: roomZ
        function roomZValueChanged(app, event)
            value = app.roomZ.Value;
            app.alsRoom(3)=value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.selected.Items = value;
            if any(strcmp(value,'Als'))
                app.AlsTab.Parent = app.TabGroup;
            else
                app.AlsTab.Parent =[];
            end
            if any(strcmp(value,'beam'))
                app.beamformingTab.Parent = app.TabGroup;
            else
                app.beamformingTab.Parent =[];
            end
        end

        % Value changed function: roomX_2
        function roomX_2ValueChanged(app, event)
            value = app.roomX_2.Value;
            app.beamRoom(1)=value;
        end

        % Value changed function: roomY_2
        function roomY_2ValueChanged(app, event)
            value = app.roomY_2.Value;
            app.beamRoom(2)=value;
        end

        % Value changed function: roomZ_2
        function roomZ_2ValueChanged(app, event)
            value = app.roomZ_2.Value;
            app.beamRoom(3)=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.processBtn = uibutton(app.UIFigure, 'push');
            app.processBtn.ButtonPushedFcn = createCallbackFcn(app, @processBtnPushed, true);
            app.processBtn.Text = 'process';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.play.Text = 'play';
            app.filePicker = uibutton(app.UIFigure, 'push');
            app.filePicker.ButtonPushedFcn = createCallbackFcn(app, @filePickerButtonPushed, true);
            app.filePicker.Text = 'select File';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Als', 'beam'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Als'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlsTab = uitab(app.TabGroup);
            app.AlsTab.Title = 'Als';
            app.RoomZLabel = uilabel(app.AlsTab);
            app.RoomZLabel.Text = 'RoomZ';
            app.roomZ = uiknob(app.AlsTab, 'continuous');
            app.roomZ.Limits = [0 10];
            app.roomZ.ValueChangedFcn = createCallbackFcn(app, @roomZValueChanged, true);
            app.RoomYLabel = uilabel(app.AlsTab);
            app.RoomYLabel.Text = 'RoomY';
            app.roomY = uiknob(app.AlsTab, 'continuous');
            app.roomY.Limits = [0 10];
            app.roomY.ValueChangedFcn = createCallbackFcn(app, @roomYValueChanged, true);
            app.RoomXLabel = uilabel(app.AlsTab);
            app.RoomXLabel.Text = 'RoomX';
            app.roomX = uiknob(app.AlsTab, 'continuous');
            app.roomX.Limits = [0 10];
            app.roomX.ValueChangedFcn = createCallbackFcn(app, @roomXValueChanged, true);
            app.beamformingTab = uitab(app.TabGroup);
            app.beamformingTab.Title = 'beamforming';
            app.RoomXLabel_2 = uilabel(app.beamformingTab);
            app.RoomXLabel_2.Text = 'RoomX';
            app.roomX_2 = uiknob(app.beamformingTab, 'continuous');
            app.roomX_2.Limits = [0 10];
            app.roomX_2.ValueChangedFcn = createCallbackFcn(app, @roomX_2ValueChanged, true);
            app.RoomYLabel_2 = uilabel(app.beamformingTab);
            app.RoomYLabel_2.Text = 'RoomY';
            app.roomY_2 = uiknob(app.beamformingTab, 'continuous');
            app.roomY_2.Limits = [0 10];
            app.roomY_2.ValueChangedFcn = createCallbackFcn(app, @roomY_2ValueChanged, true);
            app.RoomZLabel_2 = uilabel(app.beamformingTab);
            app.RoomZLabel_2.Text = 'RoomZ';
            app.roomZ_2 = uiknob(app.beamformingTab, 'continuous');
            app.roomZ_2.Limits = [0 10];
            app.roomZ_2.ValueChangedFcn = createCallbackFcn(app, @roomZ_2ValueChanged, true);
            app.selectedLabel = uilabel(app.UIFigure);
            app.selectedLabel.Text = 'selected';
            app.selected = uilistbox(app.UIFigure);
            app.selected.Items = {};
            app.selected.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end